classdef KlusterKleaner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainWin                      matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        LoadMenu                     matlab.ui.container.Menu
        SettingsMenu                 matlab.ui.container.Menu
        changeKKdefaultsMenu         matlab.ui.container.Menu
        resetdefaultsMenu            matlab.ui.container.Menu
        splitmethodDropDown          matlab.ui.control.DropDown
        splitmethodDropDownLabel     matlab.ui.control.Label
        PanelMeanWF                  matlab.ui.container.Panel
        SelectLabel                  matlab.ui.control.Label
        ListBoxChans                 matlab.ui.control.ListBox
        ReloadButton                 matlab.ui.control.Button
        savesplitButton              matlab.ui.control.Button
        plotoverlapButton            matlab.ui.control.Button
        cluIDEditField_2             matlab.ui.control.NumericEditField
        cluIDEditField_2Label        matlab.ui.control.Label
        cluIDEditField               matlab.ui.control.NumericEditField
        cluIDEditFieldLabel          matlab.ui.control.Label
        SelectclusterEditField       matlab.ui.control.NumericEditField
        SelectclusterEditFieldLabel  matlab.ui.control.Label
        DatasetEditField             matlab.ui.control.EditField
        DatasetEditFieldLabel        matlab.ui.control.Label
        splitWaveForm1               matlab.ui.control.UIAxes
        splitWaveForm2               matlab.ui.control.UIAxes
        currentWaveForm              matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        %%
        function updateGUI(app,event)
            %
            if isnan(app.mainWin.UserData.currSelInd) || isempty(app.mainWin.UserData.dataObj.spikeData.spk_waveforms{1}{app.mainWin.UserData.currSelInd,1})  
                arrayfun(@cla,app.PanelMeanWF.Children(1:end-14));
                app.ListBoxChans.Items = {};
            else
                if strcmp(event.Source.Tag,'cluChanged')
                    maxInd = app.plotMeanWaveForms;
                    app.ListBoxChans.Items = cellstr(num2str(app.mainWin.UserData.dataObj.spikeData.spk_waveforms{1}{app.mainWin.UserData.currSelInd,2}));
                    app.ListBoxChans.Value = app.ListBoxChans.Items(maxInd);
                end
            end
            %
            if isempty(app.ListBoxChans.Items)
                cla(app.currentWaveForm);
            else
                chanInd = ismember(app.ListBoxChans.Items,app.ListBoxChans.Value);
                scanpix.plot.plotWaveform(-app.mainWin.UserData.dataObj.spikeData.spk_waveforms{1}{app.mainWin.UserData.currSelInd,1}(:,:,chanInd),app.currentWaveForm,'plotIndWFs',true);
                hold(app.currentWaveForm,'on');
                if strcmp(app.splitmethodDropDown.Value,'Vt')
                    plot(app.currentWaveForm,[app.currentWaveForm.XLim(2)/2 app.currentWaveForm.XLim(2)/2],app.currentWaveForm.YLim,'g-','LineWidth',2);
                else
                    plot(app.currentWaveForm,app.currentWaveForm.XLim,[app.currentWaveForm.YLim(1) + app.currentWaveForm.YLim(2) app.currentWaveForm.YLim(1) + app.currentWaveForm.YLim(2)],'g-','LineWidth',2);
                end
                hold(app.currentWaveForm,'off');
            end
            
            if strcmp(event.EventName,'Hit')
                app.splitCluster(event);
            end


            figure(app.mainWin);  
        end
        %%
        function maxInd = plotMeanWaveForms(app)
           
            if length(app.PanelMeanWF.Children) > 14
                arrayfun(@cla,app.PanelMeanWF.Children(1:end-14));
            end
            %
            plotSz = [75 75];
            %
            idx     = ismember(app.mainWin.UserData.dataObj.chanMap(1).chanMap,[app.mainWin.UserData.dataObj.spikeData.spk_waveforms{1}{app.mainWin.UserData.currSelInd,2}]);
            xCoord = app.mainWin.UserData.dataObj.chanMap(1).xcoords(idx);
            startCoord = find(xCoord(1) == [11 43 27 59]);
            %
            xOffSet = [10 10+plotSz(1)*1.1 plotSz(1)/2 plotSz(1)/2+plotSz(1)*1.1];
            if startCoord > 2; yOffSet = 10 + plotSz(2)*1.1; else; yOffSet = 10; end
            %
            currWave = app.mainWin.UserData.dataObj.spikeData.spk_waveforms{1}{app.mainWin.UserData.currSelInd,1};
            meanWFs = squeeze(mean(-currWave,1,'omitnan'));
            [~, maxInd] = max(abs(min(meanWFs,[],1) - max(meanWFs,[],1)));
            %                 axLims  = [min(min(-currWave(:,:,maxInd))) max(max(-currWave(:,:,maxInd)))];
            axLims  = [min(min(meanWFs)) max(max(meanWFs))];
            
            c = startCoord;
            for i = 1:sum(idx)
                % plot waveform
                tempAx = axes(app.PanelMeanWF,'units','pixel','Position',[xOffSet(c)+1/8*plotSz(2) yOffSet+1/8*plotSz(2) plotSz.*0.75]);
                tempAx.Toolbar.Visible = 'off';
                scanpix.plot.plotWaveform(-currWave(:,:,i),tempAx,'plotIndWFs',false);
                set(tempAx,'ylim',axLims);
                axis(tempAx,'off');
                text(tempAx,tempAx.XLim(1)-10,axLims(2)-5,num2str(app.mainWin.UserData.dataObj.spikeData.spk_waveforms{1}{app.mainWin.UserData.currSelInd,2}(i)),'FontWeight','bold');
                % bump axis position
                if rem(c,2) == 0; yOffSet = yOffSet + plotSz(2)*1.1; end
                if rem(c,4) == 0; c = 1; else; c = c + 1; end
            end
        end
    end
    
    methods (Access = private)
        %%
        function setDefaults(app,field,value)
            if nargin == 1; [field, value] = scanpix.GUI.grabDefaults_KKGUI; end
        
            for i = 1:length(field)
                app.mainWin.UserData.GUIDefaults.(field{i}) = value{i};
            end
        end
        %%
        function plotBoxes4MeanWfs(app)
            plotSz = [75 75];

            xOffSet = [10 10+plotSz(1)*1.1 plotSz(1)/2 plotSz(1)/2+plotSz(1)*1.1];
            yOffSet = 10;

            faceAlpha = .3;
            c = 1;
            for i = 1:14
                % plot a square to indicate electrode pos on shank - not really necessary but makes figure clearer
                tempAx2 = axes(app.PanelMeanWF,'units','pixel','Position',[xOffSet(c) yOffSet plotSz]);
                tempAx2.Toolbar.Visible = 'off';
                patch(tempAx2,[0 1 1 0],[0 0 1 1],[0.3 0.3 0.3],'FaceAlpha',faceAlpha);
                axis(tempAx2,'square');
                axis(tempAx2,'off');
                %
                if rem(i,2) == 0
                    if faceAlpha == .3; faceAlpha = .1; else; faceAlpha = .3; end
                    yOffSet = yOffSet + plotSz(2)*1.1;
                end
                if rem(i,4) == 0; c = 1; else; c = c + 1; end
            end
            
        end
        
        function results = splitCluster(app,event)
            
            chanInd = ismember(app.ListBoxChans.Items,app.ListBoxChans.Value);
            waveSelect = app.mainWin.UserData.dataObj.spikeData.spk_waveforms{1}{app.mainWin.UserData.currSelInd,1}(:,:,chanInd);

            if strcmp(app.splitmethodDropDown.Value,'Vt')
                
                
            else

            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setDefaults(app);
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            % isJoined = false;
        
            [fileN,path] = uigetfile({'*.dat'; '*.ap.bin'},'Select a neuropixel data file');
            if isnumeric(path)
                warning('scaNpix::GUI:KlusterKleaner: Aborted loading data into KlusterKleaner. That''s lame.');
                return
            end

            app.mainWin.UserData.dataObj = scanpix.objLoader('npix',fullfile(path,fileN),'pos',false,'objParams','objParams4KKGUI');
            % app.mainWin.UserData.dataObj_full = app.mainWin.UserData.dataObj.deepCopy;

            app.DatasetEditField.Value       = app.mainWin.UserData.dataObj.dataSetName;
            app.mainWin.UserData.currSelInd  = NaN;
            app.SelectclusterEditField.Value = 0;

            if isempty(app.PanelMeanWF.Children)
                app.plotBoxes4MeanWfs;
            end

            figure(app.mainWin);
            % objType = 'dataObj';

            % % in case we loaded data from a cat file we have some annoying
            % % processing to do
            % if ~isempty(dir(fullfile(path,'*logFile.tsv')))
            %     % choose trial we want to load 
            %     fs      = dir(fullfile(path,'*logFile.tsv'));
            %     logFile = tdfread(fullfile(fs.folder,fs.name),'tab');
            %     indData = logFile.nDatasets == 1;
            %     % dataset names
            %     logFileDSNames = cellstr(logFile.filename(indData,:));
            %     [select, loadCheck] = listdlg('PromptString','Select what trial to load:','ListString',logFileDSNames,'ListSize',[300 200],'SelectionMode','single');
            %     if ~loadCheck
            %         warning('scaNpix::GUI::phyHelp: You need to selecrf .');
            %         return;
            %     end
            %     % first load sample times from cat sync file
            %     fs           = dir(fullfile(path,'*TTL.txt'));
            %     if isempty(fs); error(['scaNpix::GUI::phyHelp: Can''t find syncTTL.txt file in ' path '.']); end
            %     fid          = fopen(fullfile(path, fs(1).name));
            %     TTLFile      = cell2mat(textscan(fid,'%f'));
            %     fclose(fid);
            %     app.mainWin.UserData.syncData.data      = TTLFile;
            %     app.mainWin.UserData.syncData.dsNames   = logFileDSNames;
            %     trialDurs = [0;logFile.nSamples(indData)] ./ 30000 ./ logFile.nChan(1); % trial durations for all trials in cat file
            %     app.mainWin.UserData.syncData.trialDurs = trialDurs;
            % 
            %     isJoined = true;
            % end

        end

        % Value changed function: SelectclusterEditField
        function SelectclusterEditFieldValueChanged(app, event)
            cluInd = find(app.SelectclusterEditField.Value == app.mainWin.UserData.dataObj.cell_ID(:,1));
            if isempty(cluInd)
                [~,cluInd] = min(abs(app.SelectclusterEditField.Value-app.mainWin.UserData.dataObj.cell_ID(:,1)));
                app.SelectclusterEditField.Value = app.mainWin.UserData.dataObj.cell_ID(cluInd,1);
            end
            if isnan(app.mainWin.UserData.currSelInd) || isempty(app.mainWin.UserData.dataObj.spikeData.spk_waveforms{1}{cluInd,2})
                app.mainWin.UserData.dataObj.loadWaves('nwave',[],'getnch',5,'nsamp',69,'prepeak',0.4,'mode','cat','path2cat',[char(fullfile(app.mainWin.UserData.dataObj.dataPath,app.mainWin.UserData.dataObj.trialNames)) '.dat'],'clu',app.mainWin.UserData.dataObj.cell_ID(cluInd,1));
            end

            app.mainWin.UserData.currSelInd = cluInd;
            %
            app.updateGUI(event);
        end

        % Menu selected function: changeKKdefaultsMenu
        function changeKKdefaultsMenuSelected(app, event)
            prompts = fieldnames(app.mainWin.UserData.GUIDefaults);
            defVals = struct2cell(app.mainWin.UserData.GUIDefaults);
            rtn = scanpix.helpers.makeCustomUIDialogue(prompts,defVals);
            if ~isempty(rtn)
                app.setDefaults(rtn(:,1),rtn(:,2));
            end
        end

        % Menu selected function: resetdefaultsMenu
        function resetdefaultsMenuSelected(app, event)
            app.setDefaults;
        end

        % Value changed function: ListBoxChans
        function ListBoxChansValueChanged(app, event)
            app.updateGUI(event);
        end

        % Button down function: currentWaveForm
        function currentWaveFormButtonDown(app, event)
            app.updateGUI(event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainWin = uifigure('Visible', 'off');
            app.mainWin.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.mainWin);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SettingsMenu = uimenu(app.mainWin);
            app.SettingsMenu.Text = 'Settings';
            app.changeKKdefaultsMenu = uimenu(app.SettingsMenu);
            app.changeKKdefaultsMenu.MenuSelectedFcn = createCallbackFcn(app, @changeKKdefaultsMenuSelected, true);
            app.changeKKdefaultsMenu.Text = 'change KK defaults';
            app.resetdefaultsMenu = uimenu(app.SettingsMenu);
            app.resetdefaultsMenu.MenuSelectedFcn = createCallbackFcn(app, @resetdefaultsMenuSelected, true);
            app.resetdefaultsMenu.Text = 'reset defaults';
            app.currentWaveForm = uiaxes(app.mainWin);
            app.currentWaveForm.XTick = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.currentWaveForm.XTickLabel = '';
            app.currentWaveForm.YTick = [-1000 -950 -900 -850 -800 -750 -700 -650 -600 -550 -500 -450 -400 -350 -300 -250 -200 -150 -100 -50 0 50 100 150 200 250 300 350 400 450 500 550 600 650 700 750 800 850 900 950 1000];
            app.currentWaveForm.YTickLabel = '';
            app.currentWaveForm.ZTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.currentWaveForm.ButtonDownFcn = createCallbackFcn(app, @currentWaveFormButtonDown, true);
            app.splitWaveForm2 = uiaxes(app.mainWin);
            app.splitWaveForm2.XTick = [];
            app.splitWaveForm2.XTickLabel = '';
            app.splitWaveForm2.YTick = [];
            app.splitWaveForm2.YTickLabel = '';
            app.splitWaveForm2.ZTick = [];
            app.splitWaveForm1 = uiaxes(app.mainWin);
            app.splitWaveForm1.XTick = [];
            app.splitWaveForm1.XTickLabel = '';
            app.splitWaveForm1.YTick = [];
            app.splitWaveForm1.YTickLabel = '';
            app.splitWaveForm1.ZTick = [];
            app.DatasetEditFieldLabel = uilabel(app.mainWin);
            app.DatasetEditFieldLabel.Text = 'Dataset';
            app.DatasetEditField = uieditfield(app.mainWin, 'text');
            app.DatasetEditField.Editable = 'off';
            app.SelectclusterEditFieldLabel = uilabel(app.mainWin);
            app.SelectclusterEditFieldLabel.Text = 'Select cluster';
            app.SelectclusterEditField = uieditfield(app.mainWin, 'numeric');
            app.SelectclusterEditField.ValueChangedFcn = createCallbackFcn(app, @SelectclusterEditFieldValueChanged, true);
            app.SelectclusterEditField.Tag = 'cluChanged';
            app.cluIDEditFieldLabel = uilabel(app.mainWin);
            app.cluIDEditFieldLabel.Text = 'clu ID';
            app.cluIDEditField = uieditfield(app.mainWin, 'numeric');
            app.cluIDEditField_2Label = uilabel(app.mainWin);
            app.cluIDEditField_2Label.Text = 'clu ID';
            app.cluIDEditField_2 = uieditfield(app.mainWin, 'numeric');
            app.plotoverlapButton = uibutton(app.mainWin, 'push');
            app.plotoverlapButton.Text = 'plot overlap';
            app.savesplitButton = uibutton(app.mainWin, 'push');
            app.savesplitButton.Text = 'save split';
            app.ReloadButton = uibutton(app.mainWin, 'push');
            app.ReloadButton.Text = 'Reload';
            app.ListBoxChans = uilistbox(app.mainWin);
            app.ListBoxChans.Items = {};
            app.ListBoxChans.ValueChangedFcn = createCallbackFcn(app, @ListBoxChansValueChanged, true);
            app.ListBoxChans.Tag = 'chanSelect';
            app.ListBoxChans.Value = {};
            app.SelectLabel = uilabel(app.mainWin);
            app.SelectLabel.Text = {'Select '; 'channel'};
            app.PanelMeanWF = uipanel(app.mainWin);
            app.splitmethodDropDownLabel = uilabel(app.mainWin);
            app.splitmethodDropDownLabel.Text = 'split method';
            app.splitmethodDropDown = uidropdown(app.mainWin);
            app.splitmethodDropDown.Items = {'Vt', 'threshold'};
            app.splitmethodDropDown.Value = 'Vt';
            % Show the figure after all components are created
            app.mainWin.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KlusterKleaner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainWin)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainWin)
        end
    end
end