classdef phyHelpGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainWin              matlab.ui.Figure
        rateMap              matlab.ui.control.UIAxes
        cluIDEditFieldLabel  matlab.ui.control.Label
        cluID                matlab.ui.control.NumericEditField
        Previous             matlab.ui.control.Button
        Next                 matlab.ui.control.Button
        dirMap               matlab.ui.control.UIAxes
        SelectDataButton     matlab.ui.control.Button
        cluLabel             matlab.ui.control.EditField
        nSpikes              matlab.ui.control.EditField
        clulabelLabel        matlab.ui.control.Label
        nspikesLabel         matlab.ui.control.Label
        TrialName            matlab.ui.control.EditField
        PlotAltTrial         matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function objOut = copyObj(app,objStr)
            % keep a backup - cumbersome to avoid copy-on-write
            objOut = scanpix.ephys('npix','default',0);
            props =  properties('scanpix.npix');
            for p = 1:length(props)   %copy all public properties
                try   %may fail if property is read-only
                    objOut.(props{p}) = app.mainWin.UserData.(objStr).(props{p});
                catch
                    warning('failed to copy property: %s', props{p});
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            figure(app.mainWin);
            
        end

        % Value changed function: cluID
        function cluIDValueChanged(app, event)
        
            [~,cellInd]        = min(abs(app.cluID.Value - app.mainWin.UserData.dataObj.cell_ID(:,1)));
            app.cluID.Value    = app.mainWin.UserData.dataObj.cell_ID(cellInd,1);
            app.cluLabel.Value = app.mainWin.UserData.dataObj.cell_Label{cellInd,1};
            app.nSpikes.Value  = num2str(app.mainWin.UserData.nSpikes(cellInd));
%             if ~any(cellInd)
%                 warning(['scaNpix::GUI::phyHelp: ' num2str(value) ' is not a valid cluster ID!']);
%                 return;
%             end
            if isempty(app.mainWin.UserData.dataObj.maps.rate{1}{cellInd})
                app.mainWin.UserData.dataObj.maps.rate{1}{cellInd} = cell2mat(scanpix.maps.makeRateMaps(app.mainWin.UserData.dataObj.spikeData.spk_Times{1}(cellInd), app.mainWin.UserData.dataObj.posData.XY{1},...
                    app.mainWin.UserData.dataObj.spikeData.sampleT{1}, app.mainWin.UserData.dataObj.trialMetaData.ppm, app.mainWin.UserData.dataObj.posData.speed{1}, app.mainWin.UserData.dataObj.mapParams.rate));
                app.mainWin.UserData.dataObj.maps.dir{1}{cellInd} = cell2mat(scanpix.maps.makeDirMaps(app.mainWin.UserData.dataObj.spikeData.spk_Times{1}(cellInd), app.mainWin.UserData.dataObj.posData.direction{1},...
                    app.mainWin.UserData.dataObj.spikeData.sampleT{1},app.mainWin.UserData.dataObj.posData.speed{1}, app.mainWin.UserData.dataObj.mapParams.dir));
            end

            % rate map
            scanpix.plot.plotRateMap(app.mainWin.UserData.dataObj.maps.rate{1}{cellInd},app.rateMap,'colmap','jet','nsteps',11);
            axis(app.rateMap,'square');
            mapSz = size(app.mainWin.UserData.dataObj.maps.rate{1}{cellInd});
            set(app.rateMap,'ydir','normal','xlim',[0 mapSz(2)],'ylim',[0 mapSz(1)]);
            set(get(app.rateMap,'xlabel'),'String',['peakFR=' num2str(max(max(app.mainWin.UserData.dataObj.maps.rate{1}{cellInd})),'%.1f')],'Visible','On');
            % dir map
            scanpix.plot.plotDirMap(app.mainWin.UserData.dataObj.maps.dir{1}{cellInd},app.dirMap);
            set(get(app.dirMap,'xlabel'),'String',['peakFR=' num2str(max(max(app.mainWin.UserData.dataObj.maps.dir{1}{cellInd})),'%.1f')],'Visible','On');
            
            
            figure(app.mainWin);
        end

        % Button pushed function: Next
        function NextButtonPushed(app, event)
        
            [~,cellInd] = min(abs(app.cluID.Value - app.mainWin.UserData.dataObj.cell_ID(:,1)));
        
            if cellInd ~= size(app.mainWin.UserData.dataObj.cell_ID,1)
                app.cluID.Value = app.mainWin.UserData.dataObj.cell_ID(cellInd+1,1);
            else
                app.cluID.Value = app.mainWin.UserData.dataObj.cell_ID(1,1);
            end
            hgfeval({@cluIDValueChanged,app,event});
        end

        % Button pushed function: Previous
        function PreviousButtonPushed(app, event)
        
            [~,cellInd] = min(abs(app.cluID.Value - app.mainWin.UserData.dataObj.cell_ID(:,1)));
        
            if cellInd ~= 1
                app.cluID.Value = app.mainWin.UserData.dataObj.cell_ID(cellInd-1,1);
            else
                app.cluID.Value = app.mainWin.UserData.dataObj.cell_ID(end,1);
            end
            hgfeval({@cluIDValueChanged,app,event});
        end

        % Button pushed function: SelectDataButton
        function SelectDataButtonPushed(app, event)
            
            isJoined = false;
        
            if strcmp(event.Source.Text,'Select Data')
                [fileN,path] = uigetfile({'*.dat'; '*.ap.bin'},'Select a neuropixel data file');
                if isnumeric(path)
                    warning('scaNpix::GUI:PhyHelp: Aborted starting phy helper GUI. That''s lame.');
                    return
                end
                
                app.mainWin.UserData.dataObj = scanpix.objLoader('npix',fullfile(path,fileN),'pos',false,'objParams','objParams4PhyLoader');              
                app.mainWin.UserData.dataObj_full = copyObj(app,'dataObj');
                objType = 'dataObj';
            else
                path = app.mainWin.UserData.dataObj.dataPath;
                objType = 'altObj';
            end
            % in case we loaded data from a cat file we have some annoying
            % processing to do
            if ~isempty(dir(fullfile(path,'*logFile.tsv')))
                % choose trial we want to load 
                fs      = dir(fullfile(path,'*logFile.tsv'));
                logFile = tdfread(fullfile(fs.folder,fs.name),'tab');
                indData = logFile.nDatasets == 1;
                % dataset names
                logFileDSNames = cellstr(logFile.filename(indData,:));
                [select, loadCheck] = listdlg('PromptString','Select what trial to load:','ListString',logFileDSNames,'ListSize',[300 200],'SelectionMode','single');
                if ~loadCheck
                    warning('scaNpix::GUI::phyHelp: Loading joined data for the GUI doesn''t make any sense.');
                    return;
                end
                % first load sample times from cat sync file
                fs           = dir(fullfile(path,'*TTL.txt'));
                if isempty(fs); error(['scaNpix::GUI::phyHelp: Can''t find syncTTL.txt file in ' path '.']); end
                fid          = fopen(fullfile(path, fs(1).name));
                TTLFile      = cell2mat(textscan(fid,'%f'));
                fclose(fid);
                app.mainWin.UserData.syncData.data      = TTLFile;
                app.mainWin.UserData.syncData.dsNames   = logFileDSNames;
                trialDurs = [0;logFile.nSamples(indData)] ./ 30000 ./ logFile.nChan(1); % trial durations for all trials in cat file
                app.mainWin.UserData.syncData.trialDurs = trialDurs;
                
                isJoined = true;
            end
            
            if any(strcmp(event.Source.Text,'Plot diff. '))
                [select, loadCheck] = listdlg('PromptString','Select what trial to load:','ListString',app.mainWin.UserData.syncData.dsNames,'ListSize',[300 200],'SelectionMode','single');
                if ~loadCheck
                    warning('scaNpix::GUI::phyHelp: Loading joined data for the GUI doesn''t make any sense.');
                    return;
                end
                if isfield(app.mainWin.UserData,'altObj') &&  strcmp(app.mainWin.UserData.syncData.dsNames(select),app.mainWin.UserData.altObj.trialNames)
                    return % data of that trial is loaded already
                end
                app.mainWin.UserData.altObj = copyObj(app,'dataObj_full');
                TTLFile   = app.mainWin.UserData.syncData.data;
                trialDurs = app.mainWin.UserData.syncData.trialDurs;
            end
            
            
            if isJoined || any(strcmp(event.Source.Text,'Plot diff. '))
            
                % now we need to choose the actual raw binary of trial so 
                [fileN,path] = uigetfile(fullfile(path,'*ap.bin'),'Select Raw Binary For Trial');
                app.mainWin.UserData.(objType).dataPath   = path;
                app.mainWin.UserData.(objType).trialNames = fileN;
                
                if isnumeric(path)
                    warning('scaNpix::GUI::phyHelp: Trial selection cancelled. This code terminates here.');
                    return; 
                end
                
                app.mainWin.UserData.(objType).load('meta'); % reload actual trial meta data
                
                currTrialOffset = sum(trialDurs(1:select));
                % reconstruct TTL times for trial to be loaded into GUI
                tempTTL  = TTLFile - currTrialOffset;
                startInd = find(tempTTL > 0,1,'first');
                endInd   = find(tempTTL <= trialDurs(select+1),1,'last');
                tempTTL  = tempTTL(startInd:endInd);
                app.mainWin.UserData.(objType).spikeData.sampleT{1} = tempTTL - tempTTL(1);
                endIdxNPix = min( [ length(app.mainWin.UserData.(objType).spikeData.sampleT{1}), find(app.mainWin.UserData.(objType).spikeData.sampleT{1} < app.mainWin.UserData.(objType).trialMetaData(1).duration,1,'last') + 1]);
                app.mainWin.UserData.(objType).spikeData(1).sampleT{1} = app.mainWin.UserData.(objType).spikeData(1).sampleT{1}(1:endIdxNPix);
                % re-assign temporal offset in object to reflect current trial
                app.mainWin.UserData.(objType).trialMetaData(1).offSet = tempTTL(1);
                % now we need to deal with spike times
                app.mainWin.UserData.(objType).spikeData.spk_Times{1} = cellfun(@(x) x+TTLFile(1),app.mainWin.UserData.(objType).spikeData.spk_Times{1},'uni',0); % add original offset from cat file back to spike times
                if isJoined
                    app.mainWin.UserData.nSpikes = cell2mat(cellfun(@length,app.mainWin.UserData.(objType).spikeData.spk_Times{1},'uni',0));
                end
                % pick spike times for trial to be loaded
                app.mainWin.UserData.(objType).spikeData.spk_Times{1} = cellfun(@(x) x(x>=currTrialOffset & x<currTrialOffset+app.mainWin.UserData.(objType).trialMetaData.duration)-currTrialOffset,app.mainWin.UserData.(objType).spikeData.spk_Times{1},'uni',0);
                % subtract trial offset (inner 'cellfun') and remove spikes that occured before first TTL in trial
                % (outer cellfun)
                app.mainWin.UserData.(objType).spikeData.spk_Times{1} = cellfun(@(x) x(x>0),cellfun(@(x) x-tempTTL(1),app.mainWin.UserData.(objType).spikeData.spk_Times{1},'uni',0),'uni',0);
                %
                
            end
            
            app.mainWin.UserData.(objType).load('pos'); % only load now to make compatible with starting from cat file
            % add maps
            % scanpix.maps.addMaps(app.mainWin.UserData.dataObj,'rate',[],'smooth','boxcar','speedFilterFlagRMaps',true,'showWaitBar',true);
            % scanpix.maps.addMaps(app.mainWin.UserData.dataObj,'dir',[],'speedFilterFlagDMaps',true,'showWaitBar',true);
            
            indEmpty = cellfun(@isempty, app.mainWin.UserData.(objType).spikeData.spk_Times{1});
            app.mainWin.UserData.(objType).spikeData.spk_Times{1} = app.mainWin.UserData.(objType).spikeData.spk_Times{1}(~indEmpty);
            %app.mainWin.UserData.dataObj.maps.rate{1}           = app.mainWin.UserData.dataObj.maps.rate{1}(~indEmpty);
            %app.mainWin.UserData.dataObj.maps.dir{1}            = app.mainWin.UserData.dataObj.maps.dir{1}(~indEmpty);
            app.mainWin.UserData.(objType).cell_ID                = app.mainWin.UserData.(objType).cell_ID(~indEmpty,:);
            app.mainWin.UserData.(objType).cell_Label             = app.mainWin.UserData.(objType).cell_Label(~indEmpty,:);
%             app.mainWin.UserData.dataObj = npixObj;
            % sort clusters numerically to make browsing easier
            [~, sortInd]                                          = sort(app.mainWin.UserData.(objType).cell_ID(:,1),'ascend');
            app.mainWin.UserData.(objType).cell_ID                = app.mainWin.UserData.(objType).cell_ID(sortInd,:);
            app.mainWin.UserData.(objType).cell_ID(:,1)           = app.mainWin.UserData.(objType).cell_ID(:,1) - 1; % phy is 0-based index
            app.mainWin.UserData.(objType).spikeData.spk_Times{1} = app.mainWin.UserData.(objType).spikeData.spk_Times{1}(sortInd);
            app.mainWin.UserData.(objType).cell_Label             = app.mainWin.UserData.(objType).cell_Label(sortInd);
            if isJoined
                app.mainWin.UserData.nSpikes                        = app.mainWin.UserData.nSpikes(~indEmpty);
                app.mainWin.UserData.nSpikes                        = app.mainWin.UserData.nSpikes(sortInd);
            end
            if ~isempty(app.mainWin.UserData.(objType).spikeData.spk_waveforms{1})
                app.mainWin.UserData.(objType).spikeData.spk_waveforms{1} = app.mainWin.UserData.(objType).spikeData.spk_waveforms{1}(sortInd);
            end
%             f = fieldnames(app.mainWin.UserData.dataObj.maps);
%             for i = 1:length(f)
%                 if ~isempty(app.mainWin.UserData.dataObj.maps.(f{i}){1}) && ~(strcmp(f{i},'pos') && length(app.mainWin.UserData.dataObj.maps.(f{i}){1}) == 1)
%                     app.mainWin.UserData.dataObj.maps.(f{i}) = app.mainWin.UserData.dataObj.maps.(f{i}){1}(sortInd);
%                 end
%             end
            app.mainWin.UserData.(objType).maps.rate{1} = cell(length(app.mainWin.UserData.(objType).spikeData.spk_Times{1}),1);
            app.mainWin.UserData.(objType).maps.dir{1}  = cell(length(app.mainWin.UserData.(objType).spikeData.spk_Times{1}),1);
            %
            if isJoined
                app.TrialName.Value = app.mainWin.UserData.dataObj.trialNames;            
                app.cluID.Value = min(app.mainWin.UserData.dataObj.cell_ID(:,1));
                hgfeval({@cluIDValueChanged,app,event});
            end
                       
        end

        % Button pushed function: PlotAltTrial
        function PlotAltTrialPushed(app, event)
        
            hgfeval({@SelectDataButtonPushed,app,event});
            % GRACEFULL FAIL NEEDS TO GE HERE WHEN LOADING WAS CANCELLED!
            %
            [~,cellInd] = min(abs(app.cluID.Value - app.mainWin.UserData.altObj.cell_ID(:,1)));
            rMap = cell2mat(scanpix.maps.makeRateMaps(app.mainWin.UserData.altObj.spikeData.spk_Times{1}(cellInd), app.mainWin.UserData.altObj.posData.XY{1},...
                    app.mainWin.UserData.altObj.spikeData.sampleT{1}, app.mainWin.UserData.altObj.trialMetaData.ppm, app.mainWin.UserData.altObj.posData.speed{1}, app.mainWin.UserData.altObj.mapParams.rate));
            dMap = cell2mat(scanpix.maps.makeDirMaps(app.mainWin.UserData.altObj.spikeData.spk_Times{1}(cellInd), app.mainWin.UserData.altObj.posData.direction{1},...
                    app.mainWin.UserData.altObj.spikeData.sampleT{1},app.mainWin.UserData.altObj.posData.speed{1}, app.mainWin.UserData.altObj.mapParams.dir));
            %
            figure; %%MAYBE THIS COULD BE CLEVER AND PLOT IN SAME AXIS??
            subplot(1,2,1);
            scanpix.plot.plotRateMap(rMap,gca);
            axis square
            set(get(gca,'xlabel'),'String',['peakFR=' num2str(max(max(rMap)),'%.1f')],'Visible','On');
            subplot(1,2,2);
            scanpix.plot.plotDirMap(dMap,gca); 
            set(get(gca,'xlabel'),'String',['peakFR=' num2str(max(max(dMap)),'%.1f')],'Visible','On');
            
            text(gca,-2.4, 1.35,app.mainWin.UserData.altObj.trialNames{1},'interpreter','none','FontSize',12);
            %
            figure(app.mainWin);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainWin = uifigure('Visible', 'off');
            app.mainWin.Name = 'PhyHelperGUI';
            app.rateMap = uiaxes(app.mainWin);
            title(app.rateMap, '')
            xlabel(app.rateMap, '')
            ylabel(app.rateMap, '')
            app.rateMap.XTick = [];
            app.rateMap.YTick = [];
            app.cluIDEditFieldLabel = uilabel(app.mainWin);
            app.cluIDEditFieldLabel.Text = 'clu ID';
            app.cluID = uieditfield(app.mainWin, 'numeric');
            app.cluID.ValueChangedFcn = createCallbackFcn(app, @cluIDValueChanged, true);
            app.Previous = uibutton(app.mainWin, 'push');
            app.Previous.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.Previous.Text = '<';
            app.Next = uibutton(app.mainWin, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.Next.Text = '>';
            app.dirMap = uiaxes(app.mainWin);
            title(app.dirMap, '')
            xlabel(app.dirMap, '')
            ylabel(app.dirMap, '')
            app.dirMap.XTick = [];
            app.dirMap.YTick = [];
            app.SelectDataButton = uibutton(app.mainWin, 'push');
            app.SelectDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataButtonPushed, true);
            app.SelectDataButton.Text = 'Select Data';
            app.cluLabel = uieditfield(app.mainWin, 'text');
            app.cluLabel.Editable = 'off';
            app.nSpikes = uieditfield(app.mainWin, 'text');
            app.nSpikes.Editable = 'off';
            app.clulabelLabel = uilabel(app.mainWin);
            app.clulabelLabel.Text = 'clu label:';
            app.nspikesLabel = uilabel(app.mainWin);
            app.nspikesLabel.Text = 'n spikes:';
            app.TrialName = uieditfield(app.mainWin, 'text');
            app.PlotAltTrial = uibutton(app.mainWin, 'push');
            app.PlotAltTrial.ButtonPushedFcn = createCallbackFcn(app, @PlotAltTrialPushed, true);
            app.PlotAltTrial.Text = {'Plot diff. '; 'trial'};
            % Show the figure after all components are created
            app.mainWin.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = phyHelpGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainWin)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainWin)
        end
    end
end